classdef wdTrans < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        Panel            matlab.ui.container.Panel
        Panel_4          matlab.ui.container.Panel
        AxisButton       matlab.ui.control.Button
        theta            matlab.ui.control.Slider
        Label_4          matlab.ui.control.Label
        p                matlab.ui.control.EditField
        Label_3          matlab.ui.control.Label
        tips             matlab.ui.control.TextArea
        Label_2          matlab.ui.control.Label
        Panel_3          matlab.ui.container.Panel
        RX               matlab.ui.control.Slider
        XLabel           matlab.ui.control.Label
        RZ               matlab.ui.control.Slider
        ZLabel           matlab.ui.control.Label
        RY               matlab.ui.control.Slider
        YLabel           matlab.ui.control.Label
        TZ               matlab.ui.control.Slider
        ZSliderLabel     matlab.ui.control.Label
        TY               matlab.ui.control.Slider
        YSliderLabel     matlab.ui.control.Label
        TX               matlab.ui.control.Slider
        xSliderLabel     matlab.ui.control.Label
        Panel_2          matlab.ui.container.Panel
        reset            matlab.ui.control.Button
        plot             matlab.ui.control.Button
        A                matlab.ui.control.EditField
        AEditFieldLabel  matlab.ui.control.Label
        D                matlab.ui.control.EditField
        DLabel           matlab.ui.control.Label
        C                matlab.ui.control.EditField
        CLabel           matlab.ui.control.Label
        B                matlab.ui.control.EditField
        BLabel           matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Points % 图形点集
        sign=0 % Description
    end
    
    methods (Access = private)
        
        function pointdisplay(app,Pt)
           X=Pt(1,:);
           Y=Pt(2,:);
           Z=Pt(3,:);
           %绘制
            plot3(app.UIAxes,X,Y,Z,'b');
            AxisButtonPushed(app)
           %显示
          
           %提取1到3行，得到的是列向量，应该转置
           app.A.Value=num2str(Pt(1:3,1)');
           app.B.Value=num2str(Pt(1:3,2)');
           app.C.Value=num2str(Pt(1:3,3)');
           app.D.Value=num2str(Pt(1:3,4)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plot
        function plotButtonPushed(app, event)
            %点初始化
            A=str2num(app.A.Value);
            B=str2num(app.B.Value);
            C=str2num(app.C.Value);
            D=str2num(app.D.Value);
            X=[A(1) B(1) C(1) D(1) A(1)];
            Y=[A(2) B(2) C(2) D(2) A(2)];
            Z=[A(3) B(3) C(3) D(3) A(3)];
            I=[1 1 1 1 1 1 1 1];
            X=[X,X(:,3),X(:,4),X(:,2)];
            Y=[Y,Y(:,3),Y(:,4),Y(:,2)];
            Z=[Z,Z(:,3),Z(:,4),Z(:,2)];
            
            
            app.Points=[X ;Y ;Z;I]; %补齐为齐次矩阵
          
            
            %绘制
            plot3(app.UIAxes,X,Y,Z,'b');
            AxisButtonPushed(app, event)
            %标记,程序已经执行
            app.sign=1;
        end

        % Value changed function: RX, RY, RZ, TX, TY, TZ, theta
        function TXValueChanged(app, event)
            if app.sign==0
                app.tips.FontColor='r';
                app.tips.Value='位姿变换以前必须绘制点';
            else
                
           
            %变换初始化
            tx = app.TX.Value;
            ty =app.TY.Value;
            tz=app.TZ.Value;
            rx=app.RX.Value*pi/180;
            ry=app.RY.Value*pi/180;
            rz=app.RZ.Value*pi/180;
            P=transl(tx,ty,tz); %指平移变换矩阵
            R=trotz(rz)*troty(ry)*trotx(rx);
            theta= app.theta.Value*pi/180;
            pp=str2num(app.p.Value); %指轴向量
            pstd=pp./sqrt(pp(1)^2+pp(2)^2+pp(3)^2);
            
            T=angvec2tr(theta,pstd);
           
             %变换
             Point2=P*app.Points; %平移变换
             Point3=R*Point2;
             Point4=T*Point3;
            pointdisplay(app,Point4);
                
            end
            
            
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            app.TX.Value=0;
            app.TY.Value=0;
            app.TZ.Value=0;
            app.RX.Value=0;
            app.RY.Value=0;
            app.RZ.Value=0;
            app.theta.Value=0;
            TXValueChanged(app, event)
        end

        % Button pushed function: AxisButton
        function AxisButtonPushed(app, event)
            p=str2num(app.p.Value);
            x=[0 0 0 0];
            y=[0 0 0 0];
            z=[0 0 0 0];
            u=[1 0 0 p(1)];
            v=[0 1 0 p(2)];
            w=[0 0 1 p(3)];
           
            hold(app.UIAxes,"on");
            quiver3(app.UIAxes,x,y,z,u,v,w,'r','LineWidth',1)
            x1=[1 0 0];
            y1=[0 1 0];
            z1=[0 0 1];
            str={'x','y','z'};
            text(app.UIAxes,x1,y1,z1,str)
            hold(app.UIAxes,"off");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '现代设计方法';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '主界面';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '坐标')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.ZLim = [-5 5];
            app.UIAxes.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.XTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.YTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.ZTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes.ZTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '坐标点';
            app.BLabel = uilabel(app.Panel_2);
            app.BLabel.Text = '点B';
            app.B = uieditfield(app.Panel_2, 'text');
            app.B.Value = '0 2 0';
            app.CLabel = uilabel(app.Panel_2);
            app.CLabel.Text = '点C';
            app.C = uieditfield(app.Panel_2, 'text');
            app.C.Value = '0 0 2';
            app.DLabel = uilabel(app.Panel_2);
            app.DLabel.Text = '点D';
            app.D = uieditfield(app.Panel_2, 'text');
            app.D.Value = '0 0 0';
            app.AEditFieldLabel = uilabel(app.Panel_2);
            app.AEditFieldLabel.Text = '点A';
            app.A = uieditfield(app.Panel_2, 'text');
            app.A.Value = '2 0 0';
            app.plot = uibutton(app.Panel_2, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plot.Text = '绘制';
            app.reset = uibutton(app.Panel_2, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = '复位';
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_3.Title = '坐标变换';
            app.xSliderLabel = uilabel(app.Panel_3);
            app.xSliderLabel.Text = 'x平移';
            app.TX = uislider(app.Panel_3);
            app.TX.Limits = [-5 5];
            app.TX.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.YSliderLabel = uilabel(app.Panel_3);
            app.YSliderLabel.Text = 'Y平移';
            app.TY = uislider(app.Panel_3);
            app.TY.Limits = [-5 5];
            app.TY.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.ZSliderLabel = uilabel(app.Panel_3);
            app.ZSliderLabel.Text = 'Z平移';
            app.TZ = uislider(app.Panel_3);
            app.TZ.Limits = [-5 5];
            app.TZ.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.YLabel = uilabel(app.Panel_3);
            app.YLabel.Text = 'Y旋转';
            app.RY = uislider(app.Panel_3);
            app.RY.Limits = [-180 180];
            app.RY.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.ZLabel = uilabel(app.Panel_3);
            app.ZLabel.Text = 'Z旋转';
            app.RZ = uislider(app.Panel_3);
            app.RZ.Limits = [-180 180];
            app.RZ.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.XLabel = uilabel(app.Panel_3);
            app.XLabel.Text = 'X旋转';
            app.RX = uislider(app.Panel_3);
            app.RX.Limits = [-180 180];
            app.RX.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '提示';
            app.tips = uitextarea(app.Panel);
            app.tips.Value = {'1.请输入点的三维坐标，以空格隔开。依次为x，y，z示例：10 20 30。'; '2.平移变换为绝对变换，即多次x平移变换，实际上是重新x平移变换。'; '3.旋转变换的顺序固定为x→y→z，也为绝对变换。'};
            app.Panel_4 = uipanel(app.Panel);
            app.Panel_4.Title = '绕任意轴旋转';
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = '轴向量';
            app.p = uieditfield(app.Panel_4, 'text');
            app.p.Value = '1 1 1';
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '旋转角度';
            app.theta = uislider(app.Panel_4);
            app.theta.Limits = [-180 180];
            app.theta.ValueChangedFcn = createCallbackFcn(app, @TXValueChanged, true);
            app.AxisButton = uibutton(app.Panel_4, 'push');
            app.AxisButton.ButtonPushedFcn = createCallbackFcn(app, @AxisButtonPushed, true);
            app.AxisButton.Text = '显示轴';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '三维图形变换';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wdTrans

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end