classdef TwoLinkApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        ButtonGroup  matlab.ui.container.ButtonGroup
        lspb         matlab.ui.control.RadioButton
        tpoly        matlab.ui.control.RadioButton
        no_inp       matlab.ui.control.RadioButton
        q2           matlab.ui.control.Slider
        Label_2      matlab.ui.control.Label
        q1           matlab.ui.control.Slider
        Label        matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
    end


    properties (Access = private)
        Robot % Description
        MathRobot
    end

    methods (Access = private)
    
        function fkine(app)
            qangle(1)= app.q1.Value/180*3.14;
            qangle(2)= app.q2.Value/180*3.14;
            text=app.ButtonGroup.SelectedObject.Text;
            if(strcmp(text,'无插值'))
              app.MathRobot.interpolation=0;  
            elseif (strcmp(text,'五次多项式插值'))
               app.MathRobot.interpolation=1;   
            elseif (strcmp(text,'分段插值'))
                app.MathRobot.interpolation=2;  
            end
            app.MathRobot.MoveJ(qangle);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MathRobot=TwoLinkModel();
            app.MathRobot.init();
        end

        % Value changed function: q1
        function q1ValueChanged(app, event)
            fkine(app);
        end

        % Value changed function: q2
        function q2ValueChanged(app, event)
            value = app.q2.Value;
            fkine(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '机器人控制器';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '正向运动学';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '轴1';
            app.q1 = uislider(app.Panel);
            app.q1.Limits = [-180 180];
            app.q1.ValueChangedFcn = createCallbackFcn(app, @q1ValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '轴2';
            app.q2 = uislider(app.Panel);
            app.q2.Limits = [0 150];
            app.q2.ValueChangedFcn = createCallbackFcn(app, @q2ValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '插值方法';
            app.no_inp = uiradiobutton(app.ButtonGroup);
            app.no_inp.Text = '无插值';
            app.no_inp.Value = true;
            app.tpoly = uiradiobutton(app.ButtonGroup);
            app.tpoly.Text = '五次多项式插值';
            app.lspb = uiradiobutton(app.ButtonGroup);
            app.lspb.Text = '分段插值';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoLinkApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end